classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CalculateDistanceButton  matlab.ui.control.Button
        DIstanceEditField        matlab.ui.control.NumericEditField
        DIstanceEditFieldLabel   matlab.ui.control.Label
        WeightEditField          matlab.ui.control.NumericEditField
        WeightEditFieldLabel     matlab.ui.control.Label
        AgeEditField             matlab.ui.control.NumericEditField
        AgeEditFieldLabel        matlab.ui.control.Label
        HeightEditField          matlab.ui.control.NumericEditField
        HeightEditFieldLabel     matlab.ui.control.Label
        CalculateStepsButton     matlab.ui.control.Button
        Image                    matlab.ui.control.Image
        StepsEditField           matlab.ui.control.NumericEditField
        StepsEditFieldLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateStepsButton
        function CalculateStepsButtonPushed(app, event)
          
   
    height = app.HeightEditField.Value;  
    age = app.AgeEditField.Value;
    weight = app.WeightEditField.Value;
    
    
    data = load('final2.mat');  


   app.StepsEditField.Value = main(height, weight, age)


        end

        % Button pushed function: CalculateDistanceButton
        function CalculateDistanceButtonPushed(app, event)
load("final1.mat");
            lat = Position.latitude;
lon = Position.longitude;

            earthCirc = 6371 ;
            app.DIstanceEditField.Value = distance(lat, lon, earthCirc)*1000
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StepsEditFieldLabel = uilabel(app.UIFigure);
            app.StepsEditFieldLabel.Text = 'Steps';
            app.StepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'βηματα1.png');
            app.CalculateStepsButton = uibutton(app.UIFigure, 'push');
            app.CalculateStepsButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateStepsButtonPushed, true);
            app.CalculateStepsButton.Text = 'Calculate Steps';
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.WeightEditFieldLabel = uilabel(app.UIFigure);
            app.WeightEditFieldLabel.Text = 'Weight';
            app.WeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.DIstanceEditFieldLabel = uilabel(app.UIFigure);
            app.DIstanceEditFieldLabel.Text = 'DIstance';
            app.DIstanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateDistanceButton = uibutton(app.UIFigure, 'push');
            app.CalculateDistanceButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateDistanceButtonPushed, true);
            app.CalculateDistanceButton.Text = 'Calculate Distance';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end